classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Slider               matlab.ui.control.Slider
        GammaTextAreaLabel   matlab.ui.control.Label
        GammaTextArea        matlab.ui.control.TextArea
        Slider_2             matlab.ui.control.Slider
        aTextAreaLabel       matlab.ui.control.Label
        aTextArea            matlab.ui.control.TextArea
        UIAxes               matlab.ui.control.UIAxes
        generate_dataButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changing function: Slider
        function callback_1(app, event)
            gammaValue = event.Value;
            assignin('base', 'gammaValue', gammaValue);
            app.GammaTextArea.Value = num2str(gammaValue);

            x_data = evalin('base', 'x_data');
            y_data = evalin('base', 'y_data');
            unknown = evalin('base', 'unknown');
            aValue = evalin('base', 'aValue');
            classes = SVMLS(x_data, y_data, unknown, 3, gammaValue, aValue);
            
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x_data(1, 1:20), x_data(2, 1:20), 'r *')
            plot(app.UIAxes, x_data(1, 21:40), x_data(2, 21:40), 'b *')
            for i = 1:961
                if (classes(i) == 1)
                plot(app.UIAxes, unknown(1,i), unknown(2, i), 'black +')
                end
            end
            
            
        end

        % Value changing function: Slider_2
        function callback_2(app, event)
            aValue = event.Value;
            assignin('base', 'aValue', aValue);
            app.aTextArea.Value = num2str(aValue);
            
            x_data = evalin('base', 'x_data');
            y_data = evalin('base', 'y_data');
            unknown = evalin('base', 'unknown');
            gammaValue = evalin('base', 'gammaValue');
            classes = SVMLS(x_data, y_data, unknown, 3, gammaValue, aValue);
            
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x_data(1, 1:20), x_data(2, 1:20), 'r *')
            plot(app.UIAxes, x_data(1, 21:40), x_data(2, 21:40), 'b *')
            for i = 1:961
                if (classes(i) == 1)
                plot(app.UIAxes, unknown(1,i), unknown(2, i), 'black +')
                end
            end
            
            
        end

        % Button pushed function: generate_dataButton
        function generate_data(app, event)
            generate_data();
            x_data = evalin('base', 'x_data');
            
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x_data(1, 1:20), x_data(2, 1:20), 'r *')
            plot(app.UIAxes, x_data(1, 21:40), x_data(2, 21:40), 'b *')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '10'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @callback_1, true);
            app.Slider.Value = 1;
            app.GammaTextAreaLabel = uilabel(app.UIFigure);
            app.GammaTextAreaLabel.Text = 'Gamma';
            app.GammaTextArea = uitextarea(app.UIFigure);
            app.GammaTextArea.Value = {'1'};
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 10];
            app.Slider_2.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider_2.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @callback_2, true);
            app.Slider_2.Value = 1;
            app.aTextAreaLabel = uilabel(app.UIFigure);
            app.aTextAreaLabel.Text = 'a';
            app.aTextArea = uitextarea(app.UIFigure);
            app.aTextArea.Value = {'1'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.generate_dataButton = uibutton(app.UIFigure, 'push');
            app.generate_dataButton.ButtonPushedFcn = createCallbackFcn(app, @generate_data, true);
            app.generate_dataButton.Text = 'generate_data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end